classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        Button       matlab.ui.control.Button
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        GaugeLabel   matlab.ui.control.Label
        Gauge        matlab.ui.control.Gauge
        UITable      matlab.ui.control.Table
    end

    
    properties (Access = private)
        values % Description
        zValues
    end
    
    methods (Access = private)
        
        function generateMap(app, hour)
       
            load coastlines
            % Construct map in regular axes
            ax1 = worldmap('Europe');
            hPlot = plotm(coastlat, coastlon);
            land = shaperead('landareas', 'UseGeoCoords', true);
            %geoshow(gca, land, 'FaceColor', [0.5 0.7 0.5]);
            geoshow([land.Lat],[land.Lon],'Color','k')

            % Copy data into "uiaxes"
            a = ncinfo('Data/Model Combined/o3_surface_20180701000000.nc');
            chimere_ozone = {a.Variables.Name};
            table = ncread('Data/Model Combined/o3_surface_20180701000000.nc',chimere_ozone{1});
            [X] = ncread('Data/Model Combined/o3_surface_20180701000000.nc', 'lat')'; % create X value
            [Y] = ncread('Data/Model Combined/o3_surface_20180701000000.nc', 'lon')';% create Y values
            % create a mesh of valuesS
            [Y] = Y(1:698);
            [X] = X(1:398);
            X = double(X);
            Y = double(Y)';
            [X,Y] = meshgrid(X, Y);
            % Remove original axes
            
  
            Z=app.zValues(:,:,app.Slider.Value);
            surfm(X,Y,Z, 'EdgeColor','none','FaceAlpha', 0.8)
            
            copyobj(ax1.Children, app.UIAxes);
            fig1 = ax1.Parent;
            delete(fig1);
            
        end
        
        function readValuesToTable(app, pathToFiles)
            app.values= [];
            app.zValues= [];
            fileDirectory = dir('Data/24Hour/24HR_CBE_*.csv');
            for k = 1 : 25
                formatSpec = "Read Z %d : %s";
                file = fileDirectory(k).name;
                Z = readtable(['Data/24Hour/',file]);
                Z = table2array(Z);
                Z=Z';
                typeof = size(Z);
                matrixSize= "%d x %d";
                a = compose(matrixSize, typeof(1,1),typeof(1,2));
                value=compose(formatSpec,k,a);
                app.values=[app.values; value];
                app.zValues (:,:,k)=[Z];
            end
           app.UITable.Data = [app.values];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            readValuesToTable(app,'Data/24Hour/')
            generateMap(app,app.Slider.Value);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
    newval = app.Slider.Value;                         %get value from the slider
    newval = round(newval);                         %round off this value
    set(app.Slider, 'Value', newval);
    generateMap(app,app.Slider.Value);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Gauge.Value = changingValue;
 
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 25];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.Slider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [1 5 10 15 20];
            app.Slider.Value = 1;
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [1 25];
            app.Gauge.Value = 1;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end